classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SkaladecybelowaDropDown        matlab.ui.control.DropDown
        SkaladecybelowaDropDownLabel   matlab.ui.control.Label
        CzstotliwoprbkowaniaEditField  matlab.ui.control.NumericEditField
        CzstotliwoprbkowaniaEditFieldLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        SygczasowyTab                  matlab.ui.container.Tab
        czas                           matlab.ui.control.UIAxes
        AmplitudowaTab                 matlab.ui.container.Tab
        amplituda                      matlab.ui.control.UIAxes
        FazowaTab                      matlab.ui.container.Tab
        faza                           matlab.ui.control.UIAxes
        NieparametryczneTab            matlab.ui.container.Tab
        NieparametryczneDropDown       matlab.ui.control.DropDown
        NieparametryczneDropDownLabel  matlab.ui.control.Label
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ParametryczneTab               matlab.ui.container.Tab
        ParametrEditField              matlab.ui.control.NumericEditField
        ParametrEditFieldLabel         matlab.ui.control.Label
        ParametryczneDropDown          matlab.ui.control.DropDown
        ParametryczneDropDownLabel     matlab.ui.control.Label
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        GwnaprzestrzeroboczaEditField  matlab.ui.control.EditField
        GwnaprzestrzeroboczaEditFieldLabel  matlab.ui.control.Label
        WczytajzplikuButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        sig % probki sygnalu
        N % Liczba próbek sygnału
        fp %Częstotliwośc próbkowania 
        f0 %odstęp pomiędzy kolejnymi próbkami na osi
        Y % FFT 
        n
        modul
        faz %faza
        t
        f_time
        f_tick
        moduldec
        w
        tempSig2
        w1
        tempSig3
        moduldec1
        faz1
        faz2
     
    end
    
    methods (Access = private)
        
        function update(app) 


            app.N = length(app.sig);
            app.n = 0:app.N-1;
            app.w = ones(size(app.sig));

            app.f_time = 0:app.N-1;
            app.t = 0:app.N-1;

            if app.fp~=0
               app.t= (app.fp/app.N)*app.n;
               app.f_time = app.n/app.fp;

               app.czas.XLabel.String = "Czas[s]";
               app.amplituda.XLabel.String = "Częstotliwość[Hz]";
               app.faza.XLabel.String = "Częstotliwość[Hz]";
               app.UIAxes.XLabel.String = "Czas[s]";
               app.UIAxes2.XLabel.String = "Czas[s]";
               app.UIAxes3.XLabel.String = "Częstotliwość[Hz]";
               app.UIAxes4.XLabel.String = "Częstotliwość[Hz]";
               app.UIAxes5.XLabel.String = "Częstotliwość[Hz]";
               app.UIAxes6.XLabel.String = "Częstotliwość[Hz]";              
            end

            
            plot(app.czas, app.f_time,app.sig);
            
            app.Y= fft(app.sig);
            app.modul = abs(app.Y)/app.N;
            app.faz = angle(app.Y)/pi*180;
                      
            stem(app.amplituda, app.t, app.modul);

            ind = find(app.modul<1e-15);
            app.faz(ind) = NaN;
            stem(app.faza, app.t,app.faz);

  
         switch app.NieparametryczneDropDown.Value
                case "Prostokąt"
                 app.w = ones(1,app.N);
                case "Trójkątna"
                    app.w = triang(app.N+1)';
                    app.w(app.N+1) = [];                    
                case "Hanna"
                    app.w  = hann(length(app.sig),"periodic");                   
                case "Hamminga"
                    app.w  = hamming(length(app.sig),"periodic");                    
                case "Blackmana"
                    app.w = blackman(length(app.sig),"periodic");
         end
            if size(app.w,1)~=1
                app.w = app.w';
            end

            app.tempSig2= app.sig.*app.w;

            if app.SkaladecybelowaDropDown.Value == "Nie"
            app.moduldec = abs(fft(app.tempSig2)/sum(app.w));
            ylabel(app.UIAxes3, 'Amplituda')
            else
            app.moduldec = 20*log10(abs(fft(app.tempSig2)/sum(app.w)));
            ylabel(app.UIAxes3, 'Amplituda [dB]')
            end

            stem(app.UIAxes3, app.t, app.moduldec);

            app.faz1 = angle(fft(app.tempSig2)/pi*180);
            stem(app.UIAxes6, app.t, app.faz1);

            plot(app.UIAxes, app.f_time,app.sig.*app.w);
            hold(app.UIAxes,"on");
            plot(app.UIAxes, app.f_time, app.w ,'LineWidth', 2);
            hold(app.UIAxes,"off");
            
            
            app.w1 = ones(size(app.sig));          

             switch app.ParametryczneDropDown.Value              
                case "Kaisera"                    
                    app.w1 = kaiser(length(app.sig),app.ParametrEditField.Value);                   
                case "Dolpha"                  
                    app.w1  = chebwin(length(app.sig),app.ParametrEditField.Value);
                case "Gaussa"                  
                    app.w1 = gausswin(length(app.sig),app.ParametrEditField.Value);
                
             end
             if size(app.w1,1)~=1
                app.w1 = app.w1';
            end
            app.tempSig3= app.sig.*app.w1;

            if app.SkaladecybelowaDropDown.Value == "Nie"
            app.moduldec1 = abs(fft(app.tempSig3)/sum(app.w1));
            ylabel(app.UIAxes4, 'Amplituda')
            else
            app.moduldec1 = 20*log10(abs(fft(app.tempSig3)/sum(app.w1)));
            ylabel(app.UIAxes4, 'Amplituda [dB]')
            end

            stem(app.UIAxes4, app.t, app.moduldec1);

            app.faz2 = angle(fft(app.tempSig3)/pi*180);
            stem(app.UIAxes5, app.t, app.faz2);

            plot(app.UIAxes2, app.f_time,app.sig.*app.w1);
            hold(app.UIAxes2,"on");
            plot(app.UIAxes2, app.f_time, app.w1 ,'LineWidth', 2);
            hold(app.UIAxes2,"off");

            
           end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: WczytajzplikuButton
        function WczytajzplikuButtonPushed(app, event)
            [file, path] = uigetfile;
            if file~=0
                load([path, file], 'x');
                app.sig = x;

                app.czas.XLabel.String =  "Próbki";
                app.amplituda.XLabel.String =  "Próbki";
                app.faza.XLabel.String =  "Próbki";
                app.UIAxes.XLabel.String =  "Próbki";
                app.UIAxes2.XLabel.String =  "Próbki";
                app.UIAxes3.XLabel.String =  "Próbki";
                app.UIAxes4.XLabel.String =  "Próbki";
                app.UIAxes5.XLabel.String =  "Próbki";
                app.UIAxes6.XLabel.String =  "Próbki";
               
                app.update();
                
            end
        end

        % Value changed function: GwnaprzestrzeroboczaEditField
        function GwnaprzestrzeroboczaEditFieldValueChanged(app, event)
            value = app.GwnaprzestrzeroboczaEditField.Value;
            
            if value~=""  
                app.sig = evalin("base", value);
                app.czas.XLabel.String =  "Próbki";
                app.amplituda.XLabel.String =  "Próbki";
                app.faza.XLabel.String =  "Próbki";
                app.update();
            end

        end

        % Value changed function: CzstotliwoprbkowaniaEditField
        function CzstotliwoprbkowaniaEditFieldValueChanged(app, event)
            app.fp = app.CzstotliwoprbkowaniaEditField.Value;
            app.update();
        end

        % Value changed function: SkaladecybelowaDropDown
        function SkaladecybelowaDropDownValueChanged(app, event)
            value = app.SkaladecybelowaDropDown.Value;
            app.update();
        end

        % Value changed function: NieparametryczneDropDown
        function NieparametryczneDropDownValueChanged(app, event)
            value = app.NieparametryczneDropDown.Value;
            app.update();
        end

        % Value changed function: ParametrEditField
        function ParametrEditFieldValueChanged(app, event)
            value = app.ParametrEditField.Value;
            app.update();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WczytajzplikuButton = uibutton(app.UIFigure, 'push');
            app.WczytajzplikuButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajzplikuButtonPushed, true);
            app.WczytajzplikuButton.Text = 'Wczytaj z pliku';
            app.GwnaprzestrzeroboczaEditFieldLabel = uilabel(app.UIFigure);
            app.GwnaprzestrzeroboczaEditFieldLabel.Text = 'Główna przestrzeń robocza';
            app.GwnaprzestrzeroboczaEditField = uieditfield(app.UIFigure, 'text');
            app.GwnaprzestrzeroboczaEditField.ValueChangedFcn = createCallbackFcn(app, @GwnaprzestrzeroboczaEditFieldValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SygczasowyTab = uitab(app.TabGroup);
            app.SygczasowyTab.Title = 'Syg. czasowy';
            app.czas = uiaxes(app.SygczasowyTab);
            title(app.czas, 'Sygnał czasowy')
            xlabel(app.czas, 'czas [s]')
            ylabel(app.czas, 'Amplituda')
            zlabel(app.czas, 'Z')
            app.AmplitudowaTab = uitab(app.TabGroup);
            app.AmplitudowaTab.Title = 'Amplitudowa';
            app.amplituda = uiaxes(app.AmplitudowaTab);
            title(app.amplituda, 'Charakterystyka amplitudowa')
            xlabel(app.amplituda, 'f [Hz]')
            ylabel(app.amplituda, 'Amplituda')
            zlabel(app.amplituda, 'Z')
            app.FazowaTab = uitab(app.TabGroup);
            app.FazowaTab.Title = 'Fazowa';
            app.faza = uiaxes(app.FazowaTab);
            title(app.faza, 'Charakterystyka fazowa')
            xlabel(app.faza, 'f [Hz]')
            ylabel(app.faza, 'Faza')
            zlabel(app.faza, 'Z')
            app.NieparametryczneTab = uitab(app.TabGroup);
            app.NieparametryczneTab.Title = 'Nieparametryczne';
            app.UIAxes = uiaxes(app.NieparametryczneTab);
            title(app.UIAxes, 'Nieparametryczne')
            xlabel(app.UIAxes, 'Probki')
            ylabel(app.UIAxes, 'Amplituda')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.NieparametryczneTab);
            title(app.UIAxes3, 'Charakterystyka amplitudowa')
            xlabel(app.UIAxes3, 'Próbki')
            ylabel(app.UIAxes3, 'Amplituda')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes6 = uiaxes(app.NieparametryczneTab);
            title(app.UIAxes6, 'Charakterystyka fazowa')
            xlabel(app.UIAxes6, 'Próbki')
            ylabel(app.UIAxes6, 'Faza')
            zlabel(app.UIAxes6, 'Z')
            app.NieparametryczneDropDownLabel = uilabel(app.NieparametryczneTab);
            app.NieparametryczneDropDownLabel.Text = 'Nieparametryczne';
            app.NieparametryczneDropDown = uidropdown(app.NieparametryczneTab);
            app.NieparametryczneDropDown.Items = {'Prostokąt', 'Trójkątna', 'Hanna', 'Hamminga', 'Blackmana'};
            app.NieparametryczneDropDown.ValueChangedFcn = createCallbackFcn(app, @NieparametryczneDropDownValueChanged, true);
            app.NieparametryczneDropDown.Value = 'Prostokąt';
            app.ParametryczneTab = uitab(app.TabGroup);
            app.ParametryczneTab.Title = 'Parametryczne';
            app.UIAxes2 = uiaxes(app.ParametryczneTab);
            title(app.UIAxes2, 'Parametryczne')
            xlabel(app.UIAxes2, 'Próbki')
            ylabel(app.UIAxes2, 'Amplituda')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.ParametryczneTab);
            title(app.UIAxes4, 'Charakterystyka amplitudowa')
            xlabel(app.UIAxes4, 'Próbki')
            ylabel(app.UIAxes4, 'Amplituda')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.ParametryczneTab);
            title(app.UIAxes5, 'Charakterystyka fazowa')
            xlabel(app.UIAxes5, 'Próbki')
            ylabel(app.UIAxes5, 'Faza')
            zlabel(app.UIAxes5, 'Z')
            app.ParametryczneDropDownLabel = uilabel(app.ParametryczneTab);
            app.ParametryczneDropDownLabel.Text = 'Parametryczne';
            app.ParametryczneDropDown = uidropdown(app.ParametryczneTab);
            app.ParametryczneDropDown.Items = {'Kaisera', 'Dolpha', 'Gaussa'};
            app.ParametryczneDropDown.Value = 'Kaisera';
            app.ParametrEditFieldLabel = uilabel(app.ParametryczneTab);
            app.ParametrEditFieldLabel.Text = 'Parametr';
            app.ParametrEditField = uieditfield(app.ParametryczneTab, 'numeric');
            app.ParametrEditField.ValueChangedFcn = createCallbackFcn(app, @ParametrEditFieldValueChanged, true);
            app.ParametrEditField.Value = 1;
            app.CzstotliwoprbkowaniaEditFieldLabel = uilabel(app.UIFigure);
            app.CzstotliwoprbkowaniaEditFieldLabel.Text = 'Częstotliwość próbkowania';
            app.CzstotliwoprbkowaniaEditField = uieditfield(app.UIFigure, 'numeric');
            app.CzstotliwoprbkowaniaEditField.ValueChangedFcn = createCallbackFcn(app, @CzstotliwoprbkowaniaEditFieldValueChanged, true);
            app.SkaladecybelowaDropDownLabel = uilabel(app.UIFigure);
            app.SkaladecybelowaDropDownLabel.Text = 'Skala decybelowa';
            app.SkaladecybelowaDropDown = uidropdown(app.UIFigure);
            app.SkaladecybelowaDropDown.Items = {'Nie', 'Tak'};
            app.SkaladecybelowaDropDown.ValueChangedFcn = createCallbackFcn(app, @SkaladecybelowaDropDownValueChanged, true);
            app.SkaladecybelowaDropDown.Value = 'Nie';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end